classdef MainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TabletDefectDetectionSystemUIFigure  matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        PreProcessingTab   matlab.ui.container.Tab
        OriginalVideoUI    matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        ProcessingTab      matlab.ui.container.Tab
        PostProcessingTab  matlab.ui.container.Tab
        Image              matlab.ui.control.Image
        NameLabel          matlab.ui.control.Label
        WorkIDLabel        matlab.ui.control.Label
        LogOutButton       matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        UploadVideoButton  matlab.ui.control.Button
        LiveVideoButton    matlab.ui.control.Button
        CloseVideoButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        cam;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             warning('off','MATLAB:subscripting:noSubscriptsSpecified');
            app.CloseVideoButton.Visible = 'off';
            app.LiveVideoButton.Visible = 'on';
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            
        end

        % Button pushed function: LiveVideoButton
        function LiveVideoButtonPushed(app, event)
          
          app.CloseVideoButton.Visible = 'on';
          app.LiveVideoButton.Visible = 'off';
          
          app.cam = webcam;
          frame = snapshot(app.cam);
          im = image(app.OriginalVideoUI, zeros(size(frame), 'uint8'));
          axis(app.OriginalVideoUI, 'image');
          
          preview(app.cam, im);
          
%           pause;
        end

        % Button pushed function: CloseVideoButton
        function CloseVideoButtonPushed(app, event)
        
            app.LiveVideoButton.Visible = 'on';
            app.CloseVideoButton.Visible = 'off';   
            closePreview(app.cam); 
            delete (app.cam);
            cla(app.OriginalVideoUI, 'reset');
            
            app.OriginalVideoUI.Position = [24, 25, 460, 371];
            app.OriginalVideoUI.Title.String = "Original Video";
            app.OriginalVideoUI.XLabel.String = "X";
            app.OriginalVideoUI.YLabel.String = "Y";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TabletDefectDetectionSystemUIFigure = uifigure('Visible', 'off');
            app.TabletDefectDetectionSystemUIFigure.Name = 'Tablet Defect Detection System';
            app.TabGroup = uitabgroup(app.TabletDefectDetectionSystemUIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.OriginalVideoUI = uiaxes(app.PreProcessingTab);
            title(app.OriginalVideoUI, 'Original Video')
            xlabel(app.OriginalVideoUI, 'X')
            ylabel(app.OriginalVideoUI, 'Y')
            app.UIAxes2 = uiaxes(app.PreProcessingTab);
            title(app.UIAxes2, 'Pre-Processed Video')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.Image = uiimage(app.TabletDefectDetectionSystemUIFigure);
            app.NameLabel = uilabel(app.TabletDefectDetectionSystemUIFigure);
            app.NameLabel.Text = 'Name:';
            app.WorkIDLabel = uilabel(app.TabletDefectDetectionSystemUIFigure);
            app.WorkIDLabel.Text = 'Work ID:';
            app.LogOutButton = uibutton(app.TabletDefectDetectionSystemUIFigure, 'push');
            app.LogOutButton.Text = 'Log Out';
            app.Panel = uipanel(app.TabletDefectDetectionSystemUIFigure);
            app.UploadVideoButton = uibutton(app.Panel, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload Video';
            app.LiveVideoButton = uibutton(app.Panel, 'push');
            app.LiveVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LiveVideoButtonPushed, true);
            app.LiveVideoButton.Text = 'Live Video';
            app.CloseVideoButton = uibutton(app.Panel, 'push');
            app.CloseVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CloseVideoButtonPushed, true);
            app.CloseVideoButton.Visible = 'off';
            app.CloseVideoButton.Text = 'Close Video';
            % Show the figure after all components are created
            app.TabletDefectDetectionSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TabletDefectDetectionSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TabletDefectDetectionSystemUIFigure)
        end
    end
end